classdef K_Omega_SST_Initial_Conditions_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KSSTInitialConditionsCalculatorUIFigure  matlab.ui.Figure
        KSSTInitialConditionsCalculatorLabel  matlab.ui.control.Label
        TrackConditionsPanel       matlab.ui.container.Panel
        TemperatureEditFieldLabel  matlab.ui.control.Label
        TemperatureEditField       matlab.ui.control.NumericEditField
        PressureEditFieldLabel     matlab.ui.control.Label
        PressureEditField          matlab.ui.control.NumericEditField
        AirDensityLabel            matlab.ui.control.Label
        HumidityEditFieldLabel     matlab.ui.control.Label
        HumidityEditField          matlab.ui.control.NumericEditField
        DynamicViscosityLabel      matlab.ui.control.Label
        KinematicViscosityLabel    matlab.ui.control.Label
        ReynoldsNumberPanel        matlab.ui.container.Panel
        VelocityEditFieldLabel     matlab.ui.control.Label
        VelocityEditField          matlab.ui.control.NumericEditField
        ReynoldsNumberLabel        matlab.ui.control.Label
        ChordLengthEditFieldLabel  matlab.ui.control.Label
        ChordLengthEditField       matlab.ui.control.NumericEditField
        FlowTypeIndicator          matlab.ui.control.Label
        TKELabel                   matlab.ui.control.Label
        CopyTKEButton              matlab.ui.control.Button
        OmegaLabel                 matlab.ui.control.Label
        CopyOmegaButton            matlab.ui.control.Button
        FlowTypeButtonGroup        matlab.ui.container.ButtonGroup
        OverairfoilButton          matlab.ui.control.RadioButton
        PipeButton                 matlab.ui.control.RadioButton
    end

    % Application properties
    properties (Access = private)
        airDensity = 1.225 % Default density of air [kg/m^3]
        dynamicViscosity = 17.89*(10^-6); % Default Dynamic Viscosity of air [Pa*S]
        kinematicViscosity = 14.65*(10^-6); % Default Kinematic Viscosity of air [m^2/s]
        reynoldsNumber = 1712396; % Default reynolds number
        tke = 0.68075; % Default turbulent kinetic energy [J]
        w = 21.52; % Default specific disipation rate [m^2/s^3]
    end
    % Methods that handle UI updates
    methods (Access = private)
        
        function UpdateUI(app)
            % Updates label texts
            UpdateAirDensityLabel(app);
            UpdateReynoldsNumberLabel(app);
            UpdateTKEAndOmegaLabel(app);
        end
        
        % Updates AirDensityLabel inside TrackConditionsPanel
        function UpdateAirDensityLabel(app)
            % Get density
            density = app.GetAirDensity(app.TemperatureEditField.Value, app.PressureEditField.Value, app.HumidityEditField.Value);
            % Get dyn Visc
            dynv = app.getDynamicViscosity(app.TemperatureEditField.Value);
            % Calculate kinematic viscosity
            kv = getKinematicViscosity(app, dynv);
            
            % Update app properties
            app.airDensity = density;
            app.dynamicViscosity = dynv;
            app.kinematicViscosity = kv;
            
            % Update UI
            app.AirDensityLabel.Text = sprintf('Air Density: %.3f kg/m³', density);
            app.DynamicViscosityLabel.Text = sprintf('Dynamic Viscosity: %.2f µPa•S', dynv*(10^6));
            app.KinematicViscosityLabel.Text = sprintf('Kinematic Viscosity: %.2f µm²/s', kv*(10^6));
        end
        
        % Updates ReynoldsNumberLabel inside ReynoldsNumberPanel
        function UpdateReynoldsNumberLabel(app)
            % Get reynolds number
            re = app.GetReynoldsNumber(app.airDensity, app.VelocityEditField.Value, app.dynamicViscosity, app.ChordLengthEditField.Value);
            
            % Update global
            app.reynoldsNumber = re;
            
            % Update UI
            app.ReynoldsNumberLabel.Text = sprintf('Reynolds Number: %.0f', re);
            % Determines type of flow
            if(re < 2300)
                app.FlowTypeIndicator.Text = 'Laminar Flow';
                app.FlowTypeIndicator.BackgroundColor = 'g';
            elseif(re >= 2300 && re < 4000)
                app.FlowTypeIndicator.Text = 'Transient Flow';
                app.FlowTypeIndicator.BackgroundColor = 'y';
            else
                app.FlowTypeIndicator.Text = 'Turbulent Flow';
                app.FlowTypeIndicator.BackgroundColor = 'r';
            end
        end
        
        % Updates TKE & Omega Label
        function UpdateTKEAndOmegaLabel(app)
            % Get turbulent kinetic energy
            k = app.GetTKE(app.VelocityEditField.Value, app.reynoldsNumber);
            % Get specific dissipation rate
            spdr = app.GetOmega(k,app.ChordLengthEditField.Value);
            
            % Update globals
            app.tke = k;
            app.w = spdr;
            
            % Update UI
            app.TKELabel.Text = sprintf('K: %.5f J', k);
            app.OmegaLabel.Text = sprintf('ω: %.3f m²/s³', spdr);
        end
    end
    
    % Methods that handle calculations
    methods (Access = private)
        % Calculates Kinematic Viscosity for air
        function [kinV] = getKinematicViscosity(app, dv)
            % Usage: [kinV] = getKinematicViscosity(~, dv)
            % Inputs: dv = dynamic visosity for air [Pa*S]
            % Output: kinV = Kinematic Viscosity of air [m^2/s]
            
            % Calculate kinematic viscosity
            kinV = dv/app.airDensity;
        end
        % Calculates dynamic visosity for air
        function [dynV] = getDynamicViscosity(~, t)
            % Usage: [dynV] = getDynamicViscosity(~, t)
            % Inputs: t = air temperature [°C]
            % Output: dynV = dynamic visosity for air [Pa*S]
            
            % Table values for air dynamic viscosity at different temps,
            % [°C], [uPa*S]
            temps = [-75 -50 -25 -15 -10 -5 0 5 10 15 20 25 30 40 50 60 80 100];
            dynamicVisc = [13.18 14.56 15.88 16.4 16.65 16.9 17.15 17.4 17.64 17.89 18.13 18.37 18.6 19.07 19.53 19.99 20.88 21.74];
            
            % Linear interpolate across known data
            dynV = interp1(temps, dynamicVisc, t)*(10^-6);
        end
        % Calculates specific turbulent dissipation rate
        function [w] = GetOmega(~, k, l)
            % Usage: [w] = GetOmega(~, k, re, l)
            % Inputs: k = turbulent kinetic energy [J]
            %         l = characteristic length [m]
            % Output:  w = specific turbulent dissipation rate [m^2/s^3]
            
            % Calculate turbulent length scale
            i = 0.07*l;
            
            % Calculates turbulent kinetic energy disipation rate
            w = (0.09^(-1/4))*(sqrt(k)/i);
        end
        % Calculates turbulent kinetic energy
        function [k] = GetTKE(~, v, re)
            % Usage: [k] = GetTKE(v, re)
            % Inputs: v = flow velocity [m/s^2]
            %         re = reynolds number [unitless]
            % Output:  k = turbulent kinetic energy [J]
            
            % Calculate turbulent intensity
            i = 0.16*(re^(-1/8));
            
            % Calculates turbulent kinetic energy
            k = (3/2)*((v*i)^2);
        end
        % Calculates reynolds number
        function [re] = GetReynoldsNumber(~, p, v, dv, l)
            % Usage: [re] = GetReynoldsNumber(app, p, v, dv, l)
            % Inputs: p = fluid density [kg/m^3]
            %         v = velocity across cross sectional area [m/s]
            %         dv = Dynamic Viscosity of fluid [Pa * S]
            %         l = characteristic length [m]
            % Output:  re = reynolds number [unitless]
            
            % Calculate reynolds number for pipe flow
            re = (p*v*l)/dv;
        end
        
        % Updates AirDensityLabel inside TrackConditionsPanel
        function [ro] = GetAirDensity(~, t, P, hr)
            % Usage: [ro] = GetAirDensity(~, temperature, heightASL, humidity)
            % Inputs: t = ambient temperature [°C]
            %         P = pressure [Pa]
            %         hr = relative humidity [%]
            % Output:  ro = air density [kg/m^3]
            
            % Constants and definitions
            
            % Triple point of water (°K)
            T0 = 273.16;
            % Ambient temperature (°K)
            T = T0 + t;
            % Ideal gas constant [J/(mol * °K)]
            R =  8.314510;
            % Molar mass of water vapour [kg/mol]
            Mv = 18.015*10^-3;
            % Molar mass of dry air [kg/mol]
            Ma = 28.9635*10^-3;
            % [°K^-2]
            A =  1.2378847*10^-5;
            % [°K^-1]
            B = -1.9121316*10^-2;
            C = 33.93711047;
            % [°K]
            D = -6.3431645*10^3;
            % [°K/Pa]
            a0 =  1.58123*10^-6;
            % [1/Pa]
            a1 = -2.9331*10^-8;
            % [1/(°K.Pa)]
            a2 =  1.1043*10^-10;
            % [°K/Pa]
            b0 =  5.707*10^-6;
            % [1/Pa]
            b1 = -2.051*10^-8;
            % [°K/Pa]
            c0 = 1.9898*10^-4;
            % [1/Pa]
            c1 = -2.376*10^-6;
            % [°K^2/Pa^2]
            d =  1.83*10^-11;
            % [°K^2/Pa^2]
            e = -0.765*10^-8;
            
            % Saturation vapour pressure at T [Pa]
            psv = exp(A.*(T.^2) + B.*T + C + D./T);
            
            % Enhancement factor at ambient temperature and pressure
            fpt = 1.00062 + (3.14*10^-8)*P + (5.6*10^-7)*(t.^2);
            
            % Mole fraction of water vapour
            xv = hr.*fpt.*psv.*(1./P)*(10^-2);
            
            % Compressibility factor of air
            Z = 1 - ((P./T).*(a0 + a1*t + a2*(t.^2) + (b0+b1*t).*xv + (c0+c1*t).*(xv.^2))) + ((P.^2/T.^2).*(d + e.*(xv.^2)));
            
            % Air density in [kg/m^3]
            ro = (P.*Ma./(Z.*R.*T)).*(1 - xv.*(1-Mv./Ma));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TemperatureEditField
        function TemperatureEditFieldValueChanged(app, event)
            % Update UI
            UpdateUI(app);
        end

        % Value changed function: PressureEditField
        function PressureEditFieldValueChanged(app, event)
            % Update UI
            UpdateUI(app);
        end

        % Value changed function: HumidityEditField
        function HumidityEditFieldValueChanged(app, event)
            % Update UI
            UpdateUI(app);
        end

        % Value changed function: VelocityEditField
        function VelocityEditFieldValueChanged(app, event)
            UpdateUI(app);
        end

        % Callback function
        function DynamicViscEditFieldValueChanged(app, event)
            UpdateUI(app);
        end

        % Value changed function: ChordLengthEditField
        function ChordLengthEditFieldValueChanged(app, event)
            UpdateUI(app);
        end

        % Button pushed function: CopyTKEButton
        function CopyTKEButtonPushed(app, event)
            % Copy to clipboard
            clipboard('copy', app.tke);
        end

        % Button pushed function: CopyOmegaButton
        function CopyOmegaButtonPushed(app, event)
            % Copy to clipboard
            clipboard('copy', app.w);
        end

        % Selection changed function: FlowTypeButtonGroup
        function FlowTypeButtonGroupSelectionChanged(app, event)
            % Update UI to reflect physical meaning of characteristic
            % length variable for reynolds number equation
            if(app.OverairfoilButton.Value == 1)
                app.ChordLengthEditFieldLabel.Text = 'Chord Length';
            else
                app.ChordLengthEditFieldLabel.Text = 'Hydraulic Di.';
            end
            UpdateUI(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KSSTInitialConditionsCalculatorUIFigure = uifigure('Visible', 'off');
            app.KSSTInitialConditionsCalculatorUIFigure.Name = 'K-ω SST Initial Conditions Calculator';
            app.KSSTInitialConditionsCalculatorLabel = uilabel(app.KSSTInitialConditionsCalculatorUIFigure);
            app.KSSTInitialConditionsCalculatorLabel.Text = 'K-ω SST Initial Conditions Calculator';
            app.TrackConditionsPanel = uipanel(app.KSSTInitialConditionsCalculatorUIFigure);
            app.TrackConditionsPanel.Title = 'Track Conditions';
            app.TemperatureEditFieldLabel = uilabel(app.TrackConditionsPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.TrackConditionsPanel, 'numeric');
            app.TemperatureEditField.Limits = [-75 100];
            app.TemperatureEditField.ValueDisplayFormat = '%.0f °C';
            app.TemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureEditFieldValueChanged, true);
            app.TemperatureEditField.Value = 15;
            app.PressureEditFieldLabel = uilabel(app.TrackConditionsPanel);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.TrackConditionsPanel, 'numeric');
            app.PressureEditField.Limits = [0 1000000];
            app.PressureEditField.ValueDisplayFormat = '%.0f Pa';
            app.PressureEditField.ValueChangedFcn = createCallbackFcn(app, @PressureEditFieldValueChanged, true);
            app.PressureEditField.Value = 101325;
            app.AirDensityLabel = uilabel(app.TrackConditionsPanel);
            app.AirDensityLabel.Text = 'Air Density: 1.225 kg/m³';
            app.HumidityEditFieldLabel = uilabel(app.TrackConditionsPanel);
            app.HumidityEditFieldLabel.Text = 'Humidity';
            app.HumidityEditField = uieditfield(app.TrackConditionsPanel, 'numeric');
            app.HumidityEditField.Limits = [0 100];
            app.HumidityEditField.ValueDisplayFormat = '%.0f %%';
            app.HumidityEditField.ValueChangedFcn = createCallbackFcn(app, @HumidityEditFieldValueChanged, true);
            app.DynamicViscosityLabel = uilabel(app.TrackConditionsPanel);
            app.DynamicViscosityLabel.Text = 'Dynamic Viscosity: 17.89  µPa•S';
            app.KinematicViscosityLabel = uilabel(app.TrackConditionsPanel);
            app.KinematicViscosityLabel.Text = 'Kinematic Viscosity: 14.65 µm²/s';
            app.ReynoldsNumberPanel = uipanel(app.KSSTInitialConditionsCalculatorUIFigure);
            app.ReynoldsNumberPanel.Title = 'Reynolds Number';
            app.VelocityEditFieldLabel = uilabel(app.ReynoldsNumberPanel);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.ReynoldsNumberPanel, 'numeric');
            app.VelocityEditField.Limits = [0 10000];
            app.VelocityEditField.ValueDisplayFormat = '%.2f m/s';
            app.VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityEditFieldValueChanged, true);
            app.VelocityEditField.Value = 25;
            app.ReynoldsNumberLabel = uilabel(app.ReynoldsNumberPanel);
            app.ReynoldsNumberLabel.Text = 'Reynolds Number: 1712396';
            app.ChordLengthEditFieldLabel = uilabel(app.ReynoldsNumberPanel);
            app.ChordLengthEditFieldLabel.Text = 'Chord Length';
            app.ChordLengthEditField = uieditfield(app.ReynoldsNumberPanel, 'numeric');
            app.ChordLengthEditField.Limits = [0 10000];
            app.ChordLengthEditField.ValueDisplayFormat = '%.2f m';
            app.ChordLengthEditField.ValueChangedFcn = createCallbackFcn(app, @ChordLengthEditFieldValueChanged, true);
            app.ChordLengthEditField.Value = 1;
            app.FlowTypeIndicator = uilabel(app.ReynoldsNumberPanel);
            app.FlowTypeIndicator.Text = {'Turbulent Flow'; ''};
            app.TKELabel = uilabel(app.KSSTInitialConditionsCalculatorUIFigure);
            app.TKELabel.Text = 'K: 0.66345 J';
            app.CopyTKEButton = uibutton(app.KSSTInitialConditionsCalculatorUIFigure, 'push');
            app.CopyTKEButton.ButtonPushedFcn = createCallbackFcn(app, @CopyTKEButtonPushed, true);
            app.CopyTKEButton.Text = {'Copy'; ''};
            app.OmegaLabel = uilabel(app.KSSTInitialConditionsCalculatorUIFigure);
            app.OmegaLabel.Text = {'ω: 21.245 m²/s³'; ''};
            app.CopyOmegaButton = uibutton(app.KSSTInitialConditionsCalculatorUIFigure, 'push');
            app.CopyOmegaButton.ButtonPushedFcn = createCallbackFcn(app, @CopyOmegaButtonPushed, true);
            app.CopyOmegaButton.Text = {'Copy'; ''};
            app.FlowTypeButtonGroup = uibuttongroup(app.KSSTInitialConditionsCalculatorUIFigure);
            app.FlowTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FlowTypeButtonGroupSelectionChanged, true);
            app.FlowTypeButtonGroup.Title = 'Flow Type';
            app.OverairfoilButton = uiradiobutton(app.FlowTypeButtonGroup);
            app.OverairfoilButton.Text = 'Over airfoil';
            app.OverairfoilButton.Value = true;
            app.PipeButton = uiradiobutton(app.FlowTypeButtonGroup);
            app.PipeButton.Text = 'Pipe';
            % Show the figure after all components are created
            app.KSSTInitialConditionsCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = K_Omega_SST_Initial_Conditions_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KSSTInitialConditionsCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KSSTInitialConditionsCalculatorUIFigure)
        end
    end
end